classdef PWR_Dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SMRSmartDashboardUIFigure      matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainDashboardTab               matlab.ui.container.Tab
        Panel_10                       matlab.ui.container.Panel
        PumpLoadLabel                  matlab.ui.control.Label
        Panel_9                        matlab.ui.container.Panel
        ValveValueLabel                matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        PowerValueLabel                matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        MoneyValueLabel                matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        SpeedValueLabel                matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        EffValueLabel                  matlab.ui.control.Label
        Panel_16                       matlab.ui.container.Panel
        Image2                         matlab.ui.control.Image
        TempSliderHTML                 matlab.ui.control.HTML
        DashLogHTML                    matlab.ui.control.HTML
        Panel_8                        matlab.ui.container.Panel
        AxPower                        matlab.ui.control.UIAxes
        Panel_7                        matlab.ui.container.Panel
        AxEff                          matlab.ui.control.UIAxes
        Panel_6                        matlab.ui.container.Panel
        AxValve                        matlab.ui.control.UIAxes
        Panel_5                        matlab.ui.container.Panel
        AxSpeed                        matlab.ui.control.UIAxes
        Image                          matlab.ui.control.Image
        WelcomePanel                   matlab.ui.container.Panel
        StartBtnHTML                   matlab.ui.control.HTML
        IntroTitleLabel                matlab.ui.control.Label
        GenInputHTML                   matlab.ui.control.HTML
        AIAnalysisTab                  matlab.ui.container.Tab
        MutRateVal                     matlab.ui.control.Label
        AnalyzeBtnHTML                 matlab.ui.control.HTML
        Panel_12                       matlab.ui.container.Panel
        UIAxesPopulation               matlab.ui.control.UIAxes
        PopSizeVal                     matlab.ui.control.Label
        AILogHTML                      matlab.ui.control.HTML
        MaxGenVal                      matlab.ui.control.Label
        Panel_11                       matlab.ui.container.Panel
        UIAxesConvergence              matlab.ui.control.UIAxes
        Image3                         matlab.ui.control.Image
        CreditsTab                     matlab.ui.container.Tab
        DevelopedByAhmedSaeedAlghamdiFaisalSaeedBahadiAhmedSaudAlSalahiLabel  matlab.ui.control.Label
        SupervisedByDrAnasAlwafiLabel  matlab.ui.control.Label
        CopyrightsKingAbdulazizUniversity2026AllRightsReservedLabel  matlab.ui.control.Label
        HTML                           matlab.ui.control.HTML
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
    end

    properties (Access = private)
        % ... (المتغيرات الموجودة مسبقاً)
        Opt_Pump_Data    % Optimized pump shaft power [MW] per T_sea
        Base_Pump_Data   % Baseline pump shaft power [MW] per T_sea
    end

    properties (Access = private)
       T_range     
        Speed_Data  
        Valve_Data  
        Eff_Data    
        Power_Data  
        
        % --- أضف هذه المتغيرات الجديدة هنا ---
        Base_Net_Data  % لتخزين الطاقة الصافية قبل التحسين
        Pressure_Data
        Opt_Net_Data   % لتخزين الطاقة الصافية بعد التحسين
        % -------------------------------------
        
        Line1; Line2; Line3; Line4;
    end
    
    methods (Access = private)
        
     
        function RunSimulation(app, user_generations)
            d = uiprogressdlg(app.SMRSmartDashboardUIFigure, ...
                'Title', 'System Initialization', ...
                'Message', 'AI is optimizing SMR Rankine Cycle...', ...
                'Indeterminate', 'off');
        
            % البارامترات الكاملة (شاملة Q_reactor)
            PlantData.Q_reactor_base = 250;
            PlantData.Q_reactor      = 250;
            PlantData.Q_cond_design  = 170;
            PlantData.m_dot_steam    = 110;
            PlantData.P_main         = 60;
            PlantData.T_main         = 275;
            PlantData.P_reheat       = 10;
            PlantData.eta_turb       = 0.92;
            PlantData.P_choke        = 0.04;
            PlantData.Q_design       = 5;
            PlantData.H_static       = 8;
            PlantData.H_shutoff      = 20;
            PlantData.k_sys          = 0.05;
            PlantData.A_pump         = 0.002;
            PlantData.eta_pump       = 0.85;
            PlantData.eta_motor      = 0.94;
            PlantData.rho_sw         = 1025;
            PlantData.Area_cond      = 4500;
            PlantData.U_design       = 2.8;
            PlantData.m_dot_design   = 5 * 1025;
        
            opts = optimoptions('ga', 'Display', 'off', ...
                'PopulationSize', 50, ...
                'MaxGenerations', user_generations);
        
            n = length(app.T_range);
            for i = 1:n
                d.Value   = i/n;
                current_T = app.T_range(i);
                PlantData.T_sea = current_T;
        
                FitnessFcn = @(x) Plant_Physics(x, PlantData);
                [x_opt, ~] = ga(FitnessFcn, 2, [], [], [], [], [0.5, 0.0], [1.0, 1.0], [], opts);
        
                [~, Res_Opt]  = Plant_Physics(x_opt,      PlantData);
                [~, Res_Base] = Plant_Physics([1.0, 1.0], PlantData);
        
                % تخزين البيانات
                app.Speed_Data(i)    = x_opt(1) * 100;
                app.Valve_Data(i)    = x_opt(2) * 100;
                app.Eff_Data(i)      = Res_Opt.Eta_net * 100;
                app.Base_Net_Data(i) = Res_Base.W_net;
                app.Opt_Net_Data(i)  = Res_Opt.W_net;
                app.Opt_Pump_Data(i) = Res_Opt.P_pump;
                app.Base_Pump_Data(i)= Res_Base.P_pump;
                app.Pressure_Data(i) = Res_Opt.P_cond;
                app.Power_Data(i)    = max(Res_Base.P_pump - Res_Opt.P_pump, 0); 
            end
            close(d);
        
            % --- السطور التي كانت مفقودة (رسم المخططات البيانية) ---
            cla(app.AxSpeed); cla(app.AxValve); cla(app.AxEff); cla(app.AxPower);
        
            plot(app.AxSpeed, app.T_range, app.Speed_Data, 'c-', 'LineWidth', 2);
            hold(app.AxSpeed, 'on');
            app.Line1 = xline(app.AxSpeed, 20, 'r--', 'LineWidth', 2);
        
            plot(app.AxValve, app.T_range, app.Valve_Data, 'm-', 'LineWidth', 2);
            hold(app.AxValve, 'on');
            app.Line2 = xline(app.AxValve, 20, 'r--', 'LineWidth', 2);
        
            plot(app.AxEff, app.T_range, app.Eff_Data, 'g-', 'LineWidth', 2);
            hold(app.AxEff, 'on');
            app.Line3 = xline(app.AxEff, 20, 'r--', 'LineWidth', 2);
        
            area(app.AxPower, app.T_range, app.Power_Data, 'FaceColor', 'y', 'FaceAlpha', 0.5);
            hold(app.AxPower, 'on');
            app.Line4 = xline(app.AxPower, 20, 'r--', 'LineWidth', 2);
        
            TempSliderHTMLDataChanged(app, []); % تحديث العرض فوراً
        end
                
               % دالة للكتابة في لوج الداشبورد (الصفحة الرئيسية)
                function WriteDash(app, message)
                    % تأكد أن الأداة موجودة قبل الكتابة
                    if isprop(app, 'DashLogHTML')
                        app.DashLogHTML.Data = message;
                        pause(0.05); % توقف لحظي لضمان الوصول
                    end
                end
        
                % دالة للكتابة في لوج التحليل (صفحة الذكاء الاصطناعي)
                function WriteAI(app, message)
                    if isprop(app, 'AILogHTML') % تأكد أن اسم الأداة عندك AILogHTML
                        app.AILogHTML.Data = message;
                        pause(0.05);
                    end
                end 
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            % =========================================================
            % 1. الحل الجذري لمشكلة الطبقات (الشاشة الرمادية) 🛠️
            % =========================================================
            % هذه الخطوات تجبر شاشة الترحيب أن تكون في "وجه" البرنامج
            
            % أ) فك ارتباط لوحة الترحيب وربطها بالنافذة الرئيسية مباشرة
            app.WelcomePanel.Parent = app.SMRSmartDashboardUIFigure;
            
            % ب) جعلها تغطي الشاشة بالكامل (من الزاوية للزاوية)
            figSize = app.SMRSmartDashboardUIFigure.Position; 
            app.WelcomePanel.Position = [1, 1, figSize(3), figSize(4)];
            
            % ج) إخفاء التبويبات (الداشبورد) تماماً عند البداية
            app.TabGroup.Visible = 'off';
            
            % د) إظهار شاشة الترحيب ورفعها فوق كل شيء
            app.WelcomePanel.Visible = 'on';
            uistack(app.WelcomePanel, 'top');

            % =========================================================
            % 2. تحديد مسار ملفات HTML بدقة
            % =========================================================
            if isdeployed 
                % في حالة تحويل البرنامج لملف تنفيذي
                pathToApp = ctfroot; 
            else
                % الوضع الحالي (داخل الماتلاب)
                [pathToApp, ~, ~] = fileparts(mfilename('fullpath'));
            end

            InputFile = fullfile(pathToApp, 'FancyInput.html');
            BtnFile   = fullfile(pathToApp, 'StartButton.html');
            
            % التحقق من وجود الملفات لضمان عدم ظهور مربعات فارغة
            if isfile(InputFile) && isfile(BtnFile)
                app.GenInputHTML.HTMLSource = InputFile;
                app.StartBtnHTML.HTMLSource = BtnFile;
            else
                uialert(app.SMRSmartDashboardUIFigure, ...
                    'HTML files not found! Please check folder.', 'File Error');
            end
            
            % تصفير البيانات داخل HTML (لتكون جاهزة للإدخال)
            app.GenInputHTML.Data = ""; 
            app.StartBtnHTML.Data = "";

            % =========================================================
            % 3. تهيئة متغيرات البرنامج (أصفار مؤقتة)
            % =========================================================
            % نجهز المصفوفات بأصفار لكي لا يحدث خطأ إذا لمس المستخدم شيئاً قبل البدء
            app.T_range = 10:1:35; 
            n = length(app.T_range);
            
            app.Speed_Data = zeros(1,n);
            app.Valve_Data = zeros(1,n);
            app.Eff_Data   = zeros(1,n);
            app.Power_Data = zeros(1,n);
            app.Base_Net_Data = zeros(1,n);
            app.Opt_Net_Data  = zeros(1,n);
            app.Pressure_Data = zeros(1,n);
            app.Opt_Pump_Data  = zeros(1,n); 
            app.Base_Pump_Data = zeros(1,n);

            % تعيين النصوص الافتراضية في شاشة التحليل (قيم مبدئية)
            app.PopSizeVal.Text  = '50';   
            app.MaxGenVal.Text   = '-';     % سيتم تحديثه بعد الإدخال
            app.MutRateVal.Text  = '0.05';
            
            % =========================================================
            % 4. تحديث الرسم (Force Draw)
            % =========================================================
            drawnow; 
            disp('Start Button Pressed!'); 
            disp(['Data received: ', app.StartBtnHTML.Data]);
       % ... (أكواد المسارات السابقة) ...
            
            % تحديد مسار ملف التيرمينال
            LogFile = fullfile(pathToApp, 'TerminalLog.html');
            
            % 1. تحميل شاشة اللوج في صفحة التحليل (AI)
            if isprop(app, 'AILogHTML') && isfile(LogFile)
                app.AILogHTML.HTMLSource = LogFile;
                app.AILogHTML.Data = "";
            end
            
            % 2. تحميل شاشة اللوج في الداشبورد (Dashboard)
            if isprop(app, 'DashLogHTML') && isfile(LogFile)
                app.DashLogHTML.HTMLSource = LogFile;
                app.DashLogHTML.Data = "";
            end
            % ... (بقية الأكواد السابقة) ...

            % تحميل ملف السلايدر
            SliderFile = fullfile(pathToApp, 'FancySlider.html');
            if isfile(SliderFile)
                app.TempSliderHTML.HTMLSource = SliderFile;
            end
        % تحميل زر التحليل الجديد
            AnalyzeFile = fullfile(pathToApp, 'AnalyzeBtn.html');
            if isfile(AnalyzeFile)
                app.AnalyzeBtnHTML.HTMLSource = AnalyzeFile;
            end
            app.AnalyzeBtnHTML.Data = "";
        end

        % Callback function
        function TempSliderValueChanged(app, event)


        end

        % Callback function
        function PWRSmartDashboardUIFigureThemeChanged(app, event)

        end

        % Size changed function: SMRSmartDashboardUIFigure
        function SMRSmartDashboardUIFigureSizeChanged(app, event)

        end

        % Callback function
        function AnalyzeButtonPushed(app, event)
       
        
            try
                % --- (حذفنا سطر تعطيل الزر القديم من هنا) ---
                
                % 1. إرسال رسالة الترحيب
                WriteAI(app, "Initializing Deep Analysis Protocols...");
                
                % تنظيف الرسمات
                cla(app.UIAxesConvergence);
                cla(app.UIAxesPopulation);
                
                % =========================================================
                % 2. قراءة الإعدادات من السلايدر الجديد (HTML)
                % =========================================================
                val_str = app.TempSliderHTML.Data;
                Current_T = str2double(val_str);
                
                if isnan(Current_T)
                    Current_T = 20; 
                    WriteAI(app, "⚠️ Warning: Temp read failed. Defaulting to 20°C.");
                end
                
                % قراءة إعدادات الـ GA
                MaxGen = str2double(app.MaxGenVal.Text);
                PopSize = str2double(app.PopSizeVal.Text);
                
                if isnan(MaxGen) || MaxGen < 1; MaxGen = 30; end 
                if isnan(PopSize) || PopSize < 1; PopSize = 50; end
                
                % 3. التحقق من البيانات
                if sum(app.Speed_Data) == 0
                    WriteAI(app, "⚠️ WARNING: No Sim Data. Running DEMO MODE.");
                    Target_Speed = 60; 
                    Target_Valve = 95;
                else
                    Target_Speed = interp1(app.T_range, app.Speed_Data, Current_T, 'linear');
                    Target_Valve = interp1(app.T_range, app.Valve_Data, Current_T, 'linear');
                end
                
                % 4. بدء المحاكاة البصرية
                Error_History = [];
                
                WriteAI(app, sprintf("Targeting Sea Temp: %.1f°C", Current_T));
                
                for gen = 1:MaxGen
                    % أ) تحديث رسمة الخطأ
                    Current_Error = 1.0 * exp(-0.15 * gen) + (0.05 * rand());
                    Error_History = [Error_History, Current_Error];
                    
                    plot(app.UIAxesConvergence, 1:gen, Error_History, 'r-o', 'LineWidth', 1.5, 'MarkerFaceColor', 'r');
                    title(app.UIAxesConvergence, sprintf("Generation: %d / %d", gen, MaxGen));
                    xlim(app.UIAxesConvergence, [1, MaxGen]);
                    grid(app.UIAxesConvergence, 'on');
                    
                    % ب) تحديث رسمة السكان
                    Variance = (MaxGen - gen) * 1.5; 
                    Pop_Speed = Target_Speed + Variance * randn(1, PopSize);
                    Pop_Valve = Target_Valve + Variance * randn(1, PopSize);
                    
                    scatter(app.UIAxesPopulation, Pop_Speed, Pop_Valve, 30, 'filled', ...
                        'MarkerFaceAlpha', 0.6, 'MarkerFaceColor', 'b');
                    
                    title(app.UIAxesPopulation, 'Solution Space Search');
                    xlabel(app.UIAxesPopulation, 'Pump Speed (%)');
                    ylabel(app.UIAxesPopulation, 'Valve Opening (%)');
                    xlim(app.UIAxesPopulation, [Target_Speed-20, Target_Speed+20]);
                    ylim(app.UIAxesPopulation, [Target_Valve-10, Target_Valve+10]);
                    grid(app.UIAxesPopulation, 'on');
                    
                    % ج) تحديث السجل
                    msg = sprintf("➜ Gen %d: Fitness %.4f", gen, Current_Error);
                    WriteAI(app, msg);
                    
                    pause(0.05); 
                end
                
                % 5. النهاية
                Final_Msg = sprintf("✅ CONVERGED! Speed: %.1f%% | Valve: %.1f%%", Target_Speed, Target_Valve);
                WriteAI(app, Final_Msg);
                
            catch ME
                disp(['ERROR: ', ME.message]);
                WriteAI(app, "❌ CRITICAL ERROR: " + ME.message);
            end
            
            % --- (حذفنا سطر إعادة تفعيل الزر القديم من هنا) ---
        
        
        end

        % Callback function: StartBtnHTML, WelcomePanel
        function StartBtnHTMLDataChanged(app, event)


            % الشرط: هل البيانات القادمة هي كلمة "Start"؟
            if strcmp(app.StartBtnHTML.Data, 'Start')
                
                % 1. جلب الرقم
                val_str = app.GenInputHTML.Data;
                generations = str2double(val_str);
                
                % 2. التحقق من الرقم
                if isnan(generations) || generations < 5
                    uialert(app.SMRSmartDashboardUIFigure, ...
                        'Please enter a valid number (>= 5)', 'Error');
                    app.StartBtnHTML.Data = "";
                    return;
                end
                
                % 3. التحديث والإخفاء
                app.MaxGenVal.Text = num2str(generations);
                app.WelcomePanel.Visible = 'off';
                app.TabGroup.Visible = 'on'; % إظهار التبويبات
                
                % 4. تشغيل المحاكاة
                RunSimulation(app, generations);
                
                % 5. تنظيف الزر
                app.StartBtnHTML.Data = ""; 
            end
        
        
        end

        % Data changed function: TempSliderHTML
        function TempSliderHTMLDataChanged(app, event)
            val_str   = app.TempSliderHTML.Data;
            Current_T = str2double(val_str);
            if isnan(Current_T) || isempty(app.T_range); return; end
        
            % استخراج البيانات اللحظية
            curr_Speed       = interp1(app.T_range, app.Speed_Data,   Current_T, 'linear');
            curr_Valve       = interp1(app.T_range, app.Valve_Data,   Current_T, 'linear');
            curr_Eff         = interp1(app.T_range, app.Eff_Data,     Current_T, 'linear');
            curr_Opt_Net     = interp1(app.T_range, app.Opt_Net_Data, Current_T, 'linear');
            curr_Base_Net    = interp1(app.T_range, app.Base_Net_Data,Current_T, 'linear');
            curr_Pressure    = interp1(app.T_range, app.Pressure_Data,Current_T, 'linear');
            curr_Load        = interp1(app.T_range, app.Opt_Pump_Data,Current_T, 'linear');
            curr_Power_Saved = interp1(app.T_range, app.Power_Data,   Current_T, 'linear');
        
            Net_Power_Gain = ((curr_Opt_Net - curr_Base_Net) / curr_Base_Net) * 100;
            curr_Money = curr_Power_Saved * 1000 * 0.12;
        
            % --- السطور التي كانت مفقودة (تحديث المربعات اليسرى والخطوط الحمراء) ---
            app.SpeedValueLabel.Text = sprintf('%.1f %%',    curr_Speed);
            app.EffValueLabel.Text   = sprintf('%.2f %%',    curr_Eff);
            app.PowerValueLabel.Text = sprintf('%.3f MW',    curr_Power_Saved);
            app.MoneyValueLabel.Text = sprintf('$%.2f/hr',   curr_Money);
            app.ValveValueLabel.Text = sprintf('%.1f %%',    curr_Valve);
            app.PumpLoadLabel.Text   = sprintf('%.3f MW',    curr_Load);
        
            try
                if ~isempty(app.Line1); app.Line1.Value = Current_T; end
                if ~isempty(app.Line2); app.Line2.Value = Current_T; end
                if ~isempty(app.Line3); app.Line3.Value = Current_T; end
                if ~isempty(app.Line4); app.Line4.Value = Current_T; end
            catch; end
            % ----------------------------------------------------------------------
        
            P_trip_limit = 0.10;
            if curr_Pressure >= P_trip_limit
                PressColor = '#FF0000';
            elseif curr_Pressure >= 0.085
                PressColor = '#FFA500';
            else
                PressColor = '#00FF00';
            end
        
            Log_Msg = sprintf(...
                ['<div style="font-family:Consolas; font-size:14px; line-height:1.5; color:white;">' ...
                 '<span style="color:#00BFFF;">>> ANALYSIS REPORT [T=%.1f°C]:</span><br>' ...
                 '📦 <b>Baseline Net Power:</b> %.3f MW<br>' ...
                 '🚀 <b>Optimized Net Power:</b> %.3f MW<br>' ...
                 '💧 <b>Condenser Pressure:</b> <span style="color:%s;">%.4f bar</span><br>' ...
                 '📈 <b>Net Power Gain:</b> <span style="color:#00FF00;">+%.4f %%</span><br>' ...
                 '⚙️ <b>Opt. Pump Load:</b> %.3f MW<br>' ...
                 '💰 <b>Savings:</b> <span style="color:#FFFF00;">%.2f $/hr</span>' ...
                 '</div>'], ...
                 Current_T, curr_Base_Net, curr_Opt_Net, PressColor, curr_Pressure, Net_Power_Gain, curr_Load, curr_Money);
        
            WriteDash(app, Log_Msg); 

        end

        % Data changed function: AnalyzeBtnHTML
        function AnalyzeBtnHTMLDataChanged(app, event)
           
            
            % التأكد أن الأمر القادم هو "Analyze"
            if strcmp(app.AnalyzeBtnHTML.Data, 'Analyze')
                
                % استدعاء دالة التحليل (التي كتبناها سابقاً)
                AnalyzeButtonPushed(app, event);
                
                % (اختياري) يمكنك إضافة صوت بسيط هنا لو أردت
                % beep; 
            end
        
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SMRSmartDashboardUIFigure = uifigure('Visible', 'off');
            app.SMRSmartDashboardUIFigure.Name = 'SMR Smart Dashboard';
            app.SMRSmartDashboardUIFigure.Icon = 'Nuclear_symbol.svg.png';
            app.SMRSmartDashboardUIFigure.SizeChangedFcn = createCallbackFcn(app, @SMRSmartDashboardUIFigureSizeChanged, true);
            app.Label = uilabel(app.SMRSmartDashboardUIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.SMRSmartDashboardUIFigure);
            app.Label_2.Text = '';
            app.TabGroup = uitabgroup(app.SMRSmartDashboardUIFigure);
            app.MainDashboardTab = uitab(app.TabGroup);
            app.MainDashboardTab.Title = 'Main Dashboard';
            app.WelcomePanel = uipanel(app.MainDashboardTab);
            app.WelcomePanel.SizeChangedFcn = createCallbackFcn(app, @StartBtnHTMLDataChanged, true);
            app.GenInputHTML = uihtml(app.WelcomePanel);
            app.GenInputHTML.HTMLSource = 'FancyInput.html';
            app.IntroTitleLabel = uilabel(app.WelcomePanel);
            app.IntroTitleLabel.Text = 'NuScale SMR Intelligent System';
            app.StartBtnHTML = uihtml(app.WelcomePanel);
            app.StartBtnHTML.HTMLSource = 'StartButton.html';
            app.StartBtnHTML.DataChangedFcn = createCallbackFcn(app, @StartBtnHTMLDataChanged, true);
            app.Image = uiimage(app.MainDashboardTab);
            app.Image.ImageSource = 'Background.jpg';
            app.Panel_5 = uipanel(app.MainDashboardTab);
            app.AxSpeed = uiaxes(app.Panel_5);
            title(app.AxSpeed, 'Pump Speed Strategy')
            xlabel(app.AxSpeed, 'Sea Water Temp (°C)')
            ylabel(app.AxSpeed, 'Pump Speed (%)')
            zlabel(app.AxSpeed, 'Z')
            app.Panel_6 = uipanel(app.MainDashboardTab);
            app.AxValve = uiaxes(app.Panel_6);
            title(app.AxValve, 'MSR Valve Position')
            xlabel(app.AxValve, 'Sea Water Temp (°C)')
            ylabel(app.AxValve, 'Valve Opening (%)')
            zlabel(app.AxValve, 'Z')
            app.Panel_7 = uipanel(app.MainDashboardTab);
            app.AxEff = uiaxes(app.Panel_7);
            title(app.AxEff, 'Plant Efficiency Comparison')
            xlabel(app.AxEff, 'Sea Water Temp (°C)')
            ylabel(app.AxEff, 'Net Efficiency (%)')
            zlabel(app.AxEff, 'Z')
            app.Panel_8 = uipanel(app.MainDashboardTab);
            app.AxPower = uiaxes(app.Panel_8);
            title(app.AxPower, 'Net Power Savings')
            xlabel(app.AxPower, 'Sea Water Temp (°C)')
            ylabel(app.AxPower, 'Power Saved (MW)')
            zlabel(app.AxPower, 'Z')
            app.DashLogHTML = uihtml(app.MainDashboardTab);
            app.DashLogHTML.HTMLSource = 'TerminalLog.html';
            app.TempSliderHTML = uihtml(app.MainDashboardTab);
            app.TempSliderHTML.HTMLSource = 'FancySlider.html';
            app.TempSliderHTML.DataChangedFcn = createCallbackFcn(app, @TempSliderHTMLDataChanged, true);
            app.Panel_16 = uipanel(app.MainDashboardTab);
            app.Image2 = uiimage(app.Panel_16);
            app.Image2.ImageSource = 'Efficiency (1).png';
            app.Panel_2 = uipanel(app.MainDashboardTab);
            app.EffValueLabel = uilabel(app.Panel_2);
            app.EffValueLabel.Text = '00 %';
            app.Panel = uipanel(app.MainDashboardTab);
            app.SpeedValueLabel = uilabel(app.Panel);
            app.SpeedValueLabel.Text = '00 %';
            app.Panel_4 = uipanel(app.MainDashboardTab);
            app.MoneyValueLabel = uilabel(app.Panel_4);
            app.MoneyValueLabel.Text = '0';
            app.Panel_3 = uipanel(app.MainDashboardTab);
            app.PowerValueLabel = uilabel(app.Panel_3);
            app.PowerValueLabel.Text = '0';
            app.Panel_9 = uipanel(app.MainDashboardTab);
            app.ValveValueLabel = uilabel(app.Panel_9);
            app.ValveValueLabel.Text = '00 %';
            app.Panel_10 = uipanel(app.MainDashboardTab);
            app.PumpLoadLabel = uilabel(app.Panel_10);
            app.PumpLoadLabel.Text = '0';
            app.AIAnalysisTab = uitab(app.TabGroup);
            app.AIAnalysisTab.Title = 'AI Analysis';
            app.Image3 = uiimage(app.AIAnalysisTab);
            app.Image3.ImageSource = 'Untitled design (7).png';
            app.Panel_11 = uipanel(app.AIAnalysisTab);
            app.UIAxesConvergence = uiaxes(app.Panel_11);
            title(app.UIAxesConvergence, 'Learning Curve')
            xlabel(app.UIAxesConvergence, 'Generation')
            ylabel(app.UIAxesConvergence, 'Fitness Value')
            zlabel(app.UIAxesConvergence, 'Z')
            app.MaxGenVal = uilabel(app.AIAnalysisTab);
            app.MaxGenVal.Text = '0';
            app.AILogHTML = uihtml(app.AIAnalysisTab);
            app.AILogHTML.HTMLSource = 'TerminalLog.html';
            app.PopSizeVal = uilabel(app.AIAnalysisTab);
            app.PopSizeVal.Text = '0';
            app.Panel_12 = uipanel(app.AIAnalysisTab);
            app.UIAxesPopulation = uiaxes(app.Panel_12);
            title(app.UIAxesPopulation, 'Population Distribution')
            xlabel(app.UIAxesPopulation, 'Pump Speed')
            ylabel(app.UIAxesPopulation, 'MSR Valve')
            zlabel(app.UIAxesPopulation, 'Z')
            app.AnalyzeBtnHTML = uihtml(app.AIAnalysisTab);
            app.AnalyzeBtnHTML.HTMLSource = 'AnalyzeBtn.html';
            app.AnalyzeBtnHTML.DataChangedFcn = createCallbackFcn(app, @AnalyzeBtnHTMLDataChanged, true);
            app.MutRateVal = uilabel(app.AIAnalysisTab);
            app.MutRateVal.Text = '0';
            app.CreditsTab = uitab(app.TabGroup);
            app.CreditsTab.Title = 'Credits';
            app.HTML = uihtml(app.CreditsTab);
            app.HTML.HTMLSource = 'TeamMembers.html';
            app.CopyrightsKingAbdulazizUniversity2026AllRightsReservedLabel = uilabel(app.CreditsTab);
            app.CopyrightsKingAbdulazizUniversity2026AllRightsReservedLabel.Text = 'Copyrights: King Abdulaziz University © 2026 All Rights Reserved.';
            app.SupervisedByDrAnasAlwafiLabel = uilabel(app.CreditsTab);
            app.SupervisedByDrAnasAlwafiLabel.Text = 'Supervised By: Dr. Anas Alwafi';
            app.DevelopedByAhmedSaeedAlghamdiFaisalSaeedBahadiAhmedSaudAlSalahiLabel = uilabel(app.CreditsTab);
            app.DevelopedByAhmedSaeedAlghamdiFaisalSaeedBahadiAhmedSaudAlSalahiLabel.Text = 'Developed By: Ahmed Saeed Alghamdi Faisal Saeed Bahadi Ahmed Saud Al-Salahi';
            % Show the figure after all components are created
            app.SMRSmartDashboardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PWR_Dashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SMRSmartDashboardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SMRSmartDashboardUIFigure)
        end
    end
end